classdef drifter_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CenterTab                      matlab.ui.container.TabGroup
        SpectrogramTab                 matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        BeamformerPlotsTab             matlab.ui.container.Tab
        UIAxesBF                       matlab.ui.control.UIAxes
        HistogramsTab                  matlab.ui.container.Tab
        UIAxesHist                     matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        RecalculatefornewdataCheckBox  matlab.ui.control.CheckBox
        RunBeamformerButton            matlab.ui.control.Button
        SpectrogramPlotOptionsPanel    matlab.ui.container.Panel
        BFdBLabel                      matlab.ui.control.Label
        BFdBUpper                      matlab.ui.control.Spinner
        BFdBLower                      matlab.ui.control.Spinner
        TsLabel                        matlab.ui.control.Label
        XlimUpper                      matlab.ui.control.Spinner
        XlimLower                      matlab.ui.control.Spinner
        PlotElev                       matlab.ui.control.Spinner
        ElLabel                        matlab.ui.control.Label
        Channel                        matlab.ui.control.DropDown
        ChDropDownLabel                matlab.ui.control.Label
        TransparencyCheckBox           matlab.ui.control.CheckBox
        FkHzLabel                      matlab.ui.control.Label
        ClimLabel                      matlab.ui.control.Label
        YlimUpper                      matlab.ui.control.Spinner
        YlimLower                      matlab.ui.control.Spinner
        ClimUpper                      matlab.ui.control.Spinner
        ClimLower                      matlab.ui.control.Spinner
        SNRThresholdEditField          matlab.ui.control.NumericEditField
        SNRThresholdEditFieldLabel     matlab.ui.control.Label
        NTVThresholdEditField          matlab.ui.control.NumericEditField
        NTVThresholdEditFieldLabel     matlab.ui.control.Label
        PlotTypeButtonGroup            matlab.ui.container.ButtonGroup
        BeamformerPowerButton          matlab.ui.control.RadioButton
        KeepLimitsCheckBox             matlab.ui.control.CheckBox
        KEtoPERatioButton              matlab.ui.control.RadioButton
        NTVButton                      matlab.ui.control.RadioButton
        AVSElegramButton               matlab.ui.control.RadioButton
        AVSAzigramButton               matlab.ui.control.RadioButton
        SpectrogramButton              matlab.ui.control.RadioButton
        LeftPanel                      matlab.ui.container.Panel
        AdvanceTime                    matlab.ui.control.Button
        RewindTime                     matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        SpectrogramOptionsPanel        matlab.ui.container.Panel
        ResampleCheckBox               matlab.ui.control.CheckBox
        FreqRes                        matlab.ui.control.NumericEditField
        FreqResEditFieldLabel          matlab.ui.control.Label
        SampleRate                     matlab.ui.control.NumericEditField
        FsEditFieldLabel               matlab.ui.control.Label
        PercentOverlap                 matlab.ui.control.NumericEditField
        OverlapEditFieldLabel          matlab.ui.control.Label
        NFFT                           matlab.ui.control.NumericEditField
        NFFTEditFieldLabel             matlab.ui.control.Label
        AudioPlaybackPanel             matlab.ui.container.Panel
        FilterCheckBox                 matlab.ui.control.CheckBox
        StopButton                     matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        FileNavigatorPanel             matlab.ui.container.Panel
        NavigatebyfileCheckBox         matlab.ui.control.CheckBox
        TimeUTCLabel                   matlab.ui.control.Label
        SelectSecond                   matlab.ui.control.Spinner
        SelectMinute                   matlab.ui.control.Spinner
        SelectHour                     matlab.ui.control.Spinner
        ExperimentDropDown             matlab.ui.control.DropDown
        ExperimentDropDownLabel        matlab.ui.control.Label
        SelectDrifter                  matlab.ui.control.DropDown
        DrifterDropDownLabel           matlab.ui.control.Label
        SelectStep                     matlab.ui.control.NumericEditField
        StepsLabel                     matlab.ui.control.Label
        SelectDuration                 matlab.ui.control.NumericEditField
        DurationsEditFieldLabel        matlab.ui.control.Label
        SelectDate                     matlab.ui.control.DatePicker
        DateDatePickerLabel            matlab.ui.control.Label
        SelectDirectory                matlab.ui.control.EditField
        DirectoryButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        time_start_utc
        time_stop_utc
        % drifter_num
        ch_select
        ch_type
        S
        F
        T
        T_utc
        pos
        B_pow
        B_F
        bf_data
        elev_deg
        az_deg
        metrics
        plot_freq_lim
        plot_time_lim
        y
        OriginalSampleRate % Description
        AnalysisDirectory % Description
        EventName % Description
        EventTimeRange 
        DeploymentName % Description
        filelist % Description
    end
    
    properties (Access = private)
        Property20 % Description
        Property24 % Description
        Property26 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            use_remote = false;
            [datadir,procdata_basedir,gitpath,envdir] = setUpDrifterPaths(use_remote,1);
            % addpath(genpath('/Users/alaferri/GIT/sio_code/sio_research/drifter/data_analysis'))
            app.SelectDirectory.Value = datadir;
            app.AnalysisDirectory.Value = procdata_basedir;
            app.SelectDate.Value = datetime(2023,2,27);
            app.SelectHour.Value = 7;
            app.SelectMinute.Value = 31;
            app.SelectSecond.Value = 28;
            app.SelectDuration.Value = 10;

            setupDirectoryListing(app)

            app.NFFT.Value = 2^nextpow2(app.SampleRate.Value/app.FreqRes.Value); 
            app.time_start_utc = datetime(year(app.SelectDate.Value),month(app.SelectDate.Value),day(app.SelectDate.Value),...
            app.SelectHour.Value,app.SelectMinute.Value,app.SelectSecond.Value);
            app.time_stop_utc = app.time_start_utc + seconds(app.SelectDuration.Value);
            app.YlimLower.Value = 0;
            app.YlimUpper.Value = (app.SampleRate.Value/2)/1e3;
            app.XlimLower.Value = 0;
            app.XlimUpper.Value = app.SelectDuration.Value;
            app.PlotTypeButtonGroup.SelectedObject = app.SpectrogramButton;
            app.ClimLower.Value = 50;
            app.ClimUpper.Value = 160;
            app.BFdBLower.Value = 50;
            app.BFdBUpper.Value = 160;
            % app.BFLower.Value = 0;
            % app.BFUpper.Value = (app.SampleRate.Value/2)/1e3;

            app.PlotElev.Enable = false;
            app.PlotElev.Visible = false;
            app.ElLabel.Visible = false;

            if isfield(app.SampleRate,'AllowEmpty')
                app.SampleRate.AllowEmpty = 'on';
                app.NFFT.AllowEmpty = 'on';
            end

            % add a listener to the limits of the axis plot 
            addlistener(app.UIAxes, {'XLim','YLim'},'PostSet', @(hAxes,eventData)axisLimChangedCallback(hAxes,eventData,app));
            addlistener(app.UIAxesBF, {'XLim','YLim'},'PostSet', @(hAxes,eventData)axisLimChangedCallback(hAxes,eventData,app));
            
            % Get the toolbar axes
            axToolstrip = axtoolbar(app.UIAxes, 'default');
            % Get the restore view button and reset the callback function
            isRestoreButton = strcmpi({axToolstrip.Children.Icon}, 'restoreview');
            restoreButtonHandle = axToolstrip.Children(isRestoreButton);
            restoreButtonHandle.ButtonPushedFcn = @(~,ev)restoreViewCallback(app,ev);

             % Get the toolbar axes
            axToolstripBF = axtoolbar(app.UIAxesBF, 'default');
            % Get the restore view button and reset the callback function
            isRestoreButton = strcmpi({axToolstripBF.Children.Icon}, 'restoreview');
            restoreButtonHandleBF = axToolstripBF.Children(isRestoreButton);
            restoreButtonHandleBF.ButtonPushedFcn = @(~,ev)restoreViewCallback(app,ev);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            display('Loading data...')
            tic
            app.time_start_utc = datetime(year(app.SelectDate.Value),month(app.SelectDate.Value),day(app.SelectDate.Value),...
            app.SelectHour.Value,app.SelectMinute.Value,app.SelectSecond.Value);
            if seconds(app.SelectDuration.Value)==0
                warning('Duration can''t be zero!')
                return
            end
            app.time_stop_utc = app.time_start_utc + seconds(app.SelectDuration.Value);
            if app.NavigatebyfileCheckBox.Value

                % find the first good file 
                [thisfile,filetimes] = getFilesInRange(app.filelist,...
                    app.time_start_utc,app.time_start_utc+seconds(1));

                app.SelectHour.Value = hour(filetimes);
                app.SelectMinute.Value = minute(filetimes);
                app.SelectSecond.Value = second(filetimes);
                
                filenames{1} = fullfile(thisfile.folder,thisfile.name);
                app.time_start_utc = filetimes;
                app.time_stop_utc = filetimes + seconds(60);
                [ydata,fs0] = audioread(fullfile(thisfile.folder,thisfile.name));
                t_utc = filetimes:seconds(1/fs0):(filetimes + seconds(size(ydata,1)-1)/fs0);

                cfg_file_tmp = dir(fullfile(thisfile.folder,'acoustic_*.txt'));
                cfg_file{1} = fullfile(cfg_file_tmp.folder,cfg_file_tmp.name);
            else
                
                %TODO: i should decimate before forming the big matrix of
                %data..
                % TODO: i should store the previous files in memory, so i dont have
                % to reoload if they haven't changed ?
                [ydata,t_utc,fs0,filetimes,filenames,cfg_file] = readDrifterDataFilelist(...
                    str2double(app.SelectDrifter.Value),app.time_start_utc,...
                    app.time_stop_utc,app.filelist);
            end
            display('File loaded.')
            toc
            % record the original sample rate
            app.OriginalSampleRate = fs0;
            % record the event name for this deployment
            if isempty(filenames)
                fprintf('No files available for selection!\n')
                fprintf('Choose a time between %s and %s\n',...
                    app.EventTimeRange)
                return
            end
            deployname = extractBetween(filenames{1},'/Drifter','/AcousticData');
            app.DeploymentName = deployname{1};
            if isempty(ydata)
                return
            end
            t_sec = convertTo(t_utc,'posixtime'); t_sec = t_sec - t_sec(1);

            do_filter = false;
            do_resample = app.ResampleCheckBox.Value;

            fres = app.FreqRes.Value;
            fs_resample = app.SampleRate.Value;
            

            if do_resample && fs_resample<fs0

                tic
                fs = fs_resample;
%                 [ydata,t_sec] = resample(ydata,t_sec,fs());
                    disp('Decimation started')

                    R=round(fs0/fs);
                    sizz=size(ydata);
                    sizz(1)=floor(sizz(1)/2);
                    for II=1:sizz(2)
                        y(:,II)=decimate(ydata(:,II),R);
                    end
                    ydata = y; clear y;
                    disp('Decimation done')
                toc

            else
                fs = fs0;
                app.SampleRate.Value = fs;
            end
            
            if do_filter
                f1 = 2.5e3; f2 = 10e3;
                [bb,aa]= butter(4,2*[f1 f2]/fs);
                ydata = filter(bb,aa,ydata);
            end


            display('Computing spectrogram...')
            tic
            app.NFFT.Value = 2^nextpow2(fs/fres); 
            noverlp = floor(app.NFFT.Value*app.PercentOverlap.Value);
            [Sall,app.F,app.T,app.ch_select,app.ch_type,app.pos] = ...
                getDrifterSpectrogramV2(ydata,app.NFFT.Value,noverlp,app.SampleRate.Value,cfg_file{1},'stft');
            display('Spectrogram done.')
            toc

            app.y = ydata(:,app.ch_select);
            app.Channel.Items = string(1:length(app.ch_select));

            
            % [S,F,T,ch_select,sensor_type] = getDrifterSTFTV2(y,nfft,noverlp,fs,cfg_file{1},'stft');
            display('Computing directional metrics...')
            tic
            P = Sall(:,:,9);
            V = Sall(:,:,10:end);
            
            app.S = Sall; %20*log10(abs(S));
            opts.do_3D_metrics = true;
            opts.compass_offset = 0;
            opts.elevation_offset = 0;
            opts.time_avg = [];
            app.metrics = computeDirectionalMetrics(P,V,opts,1);
            app.T_utc = seconds(app.T) + t_utc(1);
            display('Directional metrics done.')
            toc

            % if we had run the beamformer, we'll need to clear it nad
            % rerun
            app.bf_data =[];
            app.B_pow = [];
            app.B_F = [];
            cla(app.UIAxesBF)
            cla(app.UIAxesHist)
            app.BeamformerPowerButton.Enable = false;
            if ~app.RecalculatefornewdataCheckBox.Value
                app.PlotTypeButtonGroup.SelectedObject = app.SpectrogramButton;
            else
                %or automatically calculate beamformer output
                RunBeamformerButtonPushed(app, event)
            end
            display('Updating plot...')
            tic
            % loading new data, reset the limits
            % app.KeepLimitsCheckBox.Value = true;
            if ~app.KeepLimitsCheckBox.Value
                app.YlimLower.Value = 0;
                app.YlimUpper.Value = 1e-3*app.SampleRate.Value/2;
            end
            app.XlimLower.Value = min(app.T);
            app.XlimUpper.Value = max(app.T);
            updateSpecPlot(app)
            display('Done.')
            toc
            % PlotTypeButtonGroupSelectionChanged(app, event)
        end

        % Value changed function: SelectDrifter
        function SelectDrifterValueChanged(app, event)
            % app.drifter_num = str2double(app.SelectDrifter.Value);
            setupDirectoryListing(app)
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotTypeButtonGroup.SelectedObject;
            
            % if we changed the plot type, we want to update the colorbar
            % limits
            switch selectedButton.Text
                case 'Spectrogram'
                    clim_default = [50 160];
                    app.PlotElev.Enable = false;
                    app.PlotElev.Visible = false;
                    app.ElLabel.Visible = false;
                    app.Channel.Enable = true;
                    app.Channel.Visible = true;
                    app.ChDropDownLabel.Visible = true;
                case 'AVS Azigram'
                    clim_default = [-180 180];
                    app.PlotElev.Enable = false;
                    app.PlotElev.Visible = false;
                    app.ElLabel.Visible = false;
                    app.Channel.Enable = true;
                    app.Channel.Visible = true;
                    app.ChDropDownLabel.Visible = true;
                case 'AVS Elegram'
                    clim_default = [-90 90];
                    app.PlotElev.Enable = false;
                    app.PlotElev.Visible = false;
                    app.ElLabel.Visible = false;
                    app.Channel.Enable = true;
                    app.Channel.Visible = true;
                    app.ChDropDownLabel.Visible = true;
                case 'NTV'
                    clim_default = [0 1];
                    app.PlotElev.Enable = false;
                    app.PlotElev.Visible = false;
                    app.ElLabel.Visible = false;
                    app.Channel.Enable = true;
                    app.Channel.Visible = true;
                    app.ChDropDownLabel.Visible = true;
                case 'KE to PE Ratio'
                    clim_default = [0 3];
                    app.PlotElev.Enable = false;
                    app.PlotElev.Visible = false;
                    app.ElLabel.Visible = false;
                    app.Channel.Enable = true;
                    app.Channel.Visible = true;
                    app.ChDropDownLabel.Visible = true;
                case 'Beamformer Power'
                    clim_default = [50 160];
                    app.PlotElev.Enable = true;
                    app.PlotElev.Visible = true;
                    app.ElLabel.Visible = true;
                    app.Channel.Enable = false;
                    app.Channel.Visible = false;
                    app.ChDropDownLabel.Visible = false;
            end
            app.ClimLower.Value = clim_default(1);
            app.ClimUpper.Value = clim_default(2);
            updateSpecPlot(app)
            % app.KeepLimitsCheckBox.Value = true;
        end

        % Button pushed function: AdvanceTime
        function AdvanceTimeButtonPushed(app, event)
            app.time_start_utc = app.time_start_utc + seconds(app.SelectStep.Value);
            app.time_stop_utc = app.time_stop_utc + seconds(app.SelectStep.Value);

            app.SelectDate.Value = app.time_start_utc;
            app.SelectHour.Value = hour(app.time_start_utc);
            app.SelectMinute.Value = minute(app.time_start_utc);
            app.SelectSecond.Value = second(app.time_start_utc);

            PlotButtonPushed(app, event)
        end

        % Button pushed function: RewindTime
        function RewindTimeButtonPushed(app, event)
            app.time_start_utc = app.time_start_utc - seconds(app.SelectStep.Value);
            app.time_stop_utc = app.time_stop_utc - seconds(app.SelectStep.Value);

            app.SelectDate.Value = app.time_start_utc;
            app.SelectHour.Value = hour(app.time_start_utc);
            app.SelectMinute.Value = minute(app.time_start_utc);
            app.SelectSecond.Value = second(app.time_start_utc);

            PlotButtonPushed(app, event)  
        end

        % Value changed function: FreqRes
        function FreqResValueChanged(app, event)
            app.NFFT.Value = 2^nextpow2(app.SampleRate.Value/app.FreqRes.Value); 
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            ch = str2double(app.Channel.Value);
            f1 = app.YlimLower.Value*1e3;
            f2 = app.YlimUpper.Value*1e3;


            if isempty(app.y)
                display('No data selected, select data by hitting "Plot"')
            else
                tidx = 1:size(app.y,1);
                % tidx = app.T <= app.XlimUpper.Value & app.T>=app.XlimLower.Value;
                if app.FilterCheckBox.Value & f2 ~= app.SampleRate.Value/2 && f1 ~= 0 
                    if f2 == app.SampleRate.Value/2 && f1 ~= 0 
                        [bb,aa]= butter(2,2*f1/app.SampleRate.Value,'high');
                    elseif f2 ~= app.SampleRate.Value/2 && f1 == 0 
                        [bb,aa]= butter(2,2*f2/app.SampleRate.Value,'low');
                    else
                        [bb,aa]= butter(2,2*[f1 f2]/app.SampleRate.Value);
                    end
                    yfilt = filter(bb,aa,app.y(tidx,ch));
                else
                    yfilt = app.y(tidx,ch);
                end
                soundsc(yfilt,app.SampleRate.Value)
            end
        end

        % Button pushed function: DirectoryButton
        function DirectoryButtonPushed(app, event)
            selpath = uigetdir;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if ~isempty(selpath)&&~isnumeric(selpath)
                app.SelectDirectory.Value = selpath;
                setupDirectoryListing(app);
            end
        end

        % Value changed function: NTVThresholdEditField
        function NTVThresholdEditFieldValueChanged(app, event)
            updateSpecPlot(app)  
        end

        % Value changed function: SNRThresholdEditField
        function SNRThresholdEditFieldValueChanged(app, event)
            updateSpecPlot(app)  
        end

        % Value changed function: SelectDate
        function SelectDateValueChanged(app, event)
            value = app.SelectDate.Value;
            % Extract just the date part
            % Reduce everything to just the date (no time)
            value_day = dateshift(value, 'start', 'day');
            start_days = dateshift(app.EventTimeRange(1,:), 'start', 'day');
            stop_days  = dateshift(app.EventTimeRange(2,:), 'start', 'day');
            
            % Find which event range (by day) contains 'value'
            idx = find(value_day >= start_days & value_day <= stop_days, 1);

            app.SelectHour.Value = hour(app.EventTimeRange(1,idx));
            app.SelectMinute.Value = minute(app.EventTimeRange(1,idx));
            app.SelectSecond.Value = second(app.EventTimeRange(1,idx));
        end

        % Value changed function: ExperimentDropDown
        function ExperimentDropDownValueChanged(app, event)
            setupDirectoryListing(app)
        end

        % Value changed function: ClimLower
        function ClimLowerValueChanged(app, event)
            value = app.ClimLower.Value;
            ca = clim(app.UIAxes);
            clim(app.UIAxes,[value,ca(2)])
        end

        % Value changed function: ClimUpper
        function ClimUpperValueChanged(app, event)
            value = app.ClimUpper.Value;
            ca = clim(app.UIAxes);
            clim(app.UIAxes,[ca(1),value])
        end

        % Value changed function: Channel
        function ChannelValueChanged(app, event)
            value = app.Channel.Value;
            updateSpecPlot(app)
        end

        % Value changed function: YlimLower
        function YlimLowerValueChanged(app, event)
            value = app.YlimLower.Value;
            ya = ylim(app.UIAxes);
            ylim(app.UIAxes,[value,ya(2)])     

            updateBFData(app)
            CenterTabSelectionChanged(app, event)
        end

        % Value changed function: YlimUpper
        function YlimUpperValueChanged(app, event)
            value = app.YlimUpper.Value;
            ya = ylim(app.UIAxes);
            ylim(app.UIAxes,[ya(1),value])    

            updateBFData(app)
            CenterTabSelectionChanged(app, event)
        end

        % Button pushed function: RunBeamformerButton
        function RunBeamformerButtonPushed(app, event)
            if isempty(app.S)
                display('No data selected, select data by hitting "Plot"')
            else
                fprintf('Running freq domain beamformer\n')
               
                array_type =  'line';
                app.elev_deg = -90:0.5:90;
                switch array_type
                    case 'line'
                        channel_select = [1 4 5 6 7 8];
                        app.az_deg = 0;
                    otherwise
                        error('Array type not defined')
                end
                % [AZ,EL] = meshgrid(app.az_deg,app.elev_deg);
                % [~,B_pow] = beamform_from_spec(app.S(:,:,channel_select),app.F,az_deg,elev_deg,app.pos(channel_select,:),[],1);
                [app.B_pow,app.B_F]= beamform_from_spec_avg(app.S(:,:,channel_select),app.T,...
                     app.F,app.az_deg,app.elev_deg,app.pos(channel_select,:),[],3e3);
                app.BeamformerPowerButton.Enable = true;
                app.PlotElev.Limits = [min(app.elev_deg) max(app.elev_deg)];
                app.PlotElev.Step = min(diff(app.elev_deg));
    
                updateBFData(app)
            end
        end

        % Value changed function: PlotElev
        function PlotElevValueChanged2(app, event)
            value = app.PlotElev.Value;
            updateSpecPlot(app)   
        end

        % Selection change function: CenterTab
        function CenterTabSelectionChanged(app, event)
            selectedTab = app.CenterTab.SelectedTab;
            switch selectedTab
                % case app.LongTermSpectraTab
                    % event_name = sprintf('Drifter%i_Acoustic%i_%s_%s',app.SelectDrifter.Value,...
                    %     driftlog.AcousticSphere(di),...
                    %     datestr(dive_time_start_utc,'YYYYmmDDThhMMss'),...
                    %     datestr(dive_time_stop_utc,'YYYYmmDDThhMMss'));
                    % long_term_spectra_file = fullfile(app.AnalysisDirectory,event_name0);
            %     case app.BeamformerPlotsTab
            %         % p=pcolor(app.UIAxesBF,app.T_utc,app.bf_data.el_deg,...
            %         %     10*log10(abs(squeeze(app.bf_data.B_incoh(:,:,app.bf_data.az_deg==0)).')));
            %         % p.ZData = p.CData;
            %         % shading(app.UIAxesBF,'flat')
            %         % colorbar(app.UIAxesBF)
            %         % colormap(app.UIAxesBF,'jet')
            %         % xlabel(app.UIAxesBF,'Time, s')
            %         % ylabel(app.UIAxesBF,'Elevation, deg')
            %         % 
            %         % ya = [min(app.elev_deg) max(app.elev_deg)];
            %         % xa = [app.T_utc(1) app.T_utc(end)]; 
            %         % % ca = [app.ClimLower.Value app.ClimUpper.Value];
            %         % xlim(app.UIAxesBF,xa); ylim(app.UIAxesBF,ya); %clim(app.UIAxesBF,ca);
            %         % clim(app.UIAxesBF,'auto')
            %         % 
            %         % title(app.UIAxesBF,sprintf('Beamformer Power, %1.1f kHz - %1.2f kHz',app.bf_data.f_band(1)/1e3,app.bf_data.f_band(2)/1e3))
                case app.HistogramsTab
                    app.NTVButton.Enable = false;
                    app.KEtoPERatioButton.Enable = false;
                    app.SpectrogramButton.Enable = false;
                    app.AVSElegramButton.Enable = false;
                    app.AVSAzigramButton.Enable = false;
                    app.Channel.Enable = false;
                    app.BeamformerPowerButton.Enable = true;
                    app.ClimUpper.Enable = false; app.ClimLower.Enable = false;
                    app.ClimUpper.Visible = false; app.ClimLower.Visible = false;
                    app.ClimLabel.Visible = false;
                    app.BFdBLabel.Visible = true;
                    app.BFdBUpper.Visible = true;
                    app.BFdBLower.Visible = true;

                    app.NTVThresholdEditField.Enable = false;
                    app.SNRThresholdEditField.Enable = false;
                    app.TransparencyCheckBox.Enable = false;
                    app.PlotTypeButtonGroup.SelectedObject = app.BeamformerPowerButton;
                    PlotTypeButtonGroupSelectionChanged(app, event)
                    % app.KeepLimitsCheckBox.Visible = false;
                    updateBFData(app)
                case app.BeamformerPlotsTab
                    app.NTVButton.Enable = false;
                    app.KEtoPERatioButton.Enable = false;
                    app.SpectrogramButton.Enable = false;
                    app.AVSElegramButton.Enable = false;
                    app.AVSAzigramButton.Enable = false;
                    app.Channel.Enable = false;
                    app.BeamformerPowerButton.Enable = true;
                    app.ClimUpper.Enable = false; app.ClimLower.Enable = false;
                    app.ClimUpper.Visible = false; app.ClimLower.Visible = false;
                    app.ClimLabel.Visible = false;
                    app.BFdBLabel.Visible = true;
                    app.BFdBUpper.Visible = true;
                    app.BFdBLower.Visible = true;
                    app.NTVThresholdEditField.Enable = false;
                    app.SNRThresholdEditField.Enable = false;
                    app.TransparencyCheckBox.Enable = false;
                    app.PlotTypeButtonGroup.SelectedObject = app.BeamformerPowerButton;
                    PlotTypeButtonGroupSelectionChanged(app, event)
                    updateBFData(app)
            %         % keyboard
            %         % bar(app.UIAxesHist,app.bf_data.el_centers,app.bf_data.elev_hist)
            %         % grid(app.UIAxesHist,'on')
            %         % xlabel(app.UIAxesHist,'Elevation, deg')
            %         % ylabel(app.UIAxesHist,'Count')
            %         % title(app.UIAxesHist,sprintf('Histogram, %1.1f kHz - %1.2f kHz',app.bf_data.f_band(1)/1e3,app.bf_data.f_band(2)/1e3))
            %         % histogram2(10*log10(B_pow_max),max_pow_el,'facecolor','flat'); 
            % 
                case app.SpectrogramTab
                    app.NTVButton.Enable = true;
                    app.KEtoPERatioButton.Enable = true;
                    app.SpectrogramButton.Enable = true;
                    app.AVSElegramButton.Enable = true;
                    app.AVSAzigramButton.Enable = true;
                    app.Channel.Enable = true;
                    app.BeamformerPowerButton.Enable = true;
                    app.NTVThresholdEditField.Enable = true;
                    app.SNRThresholdEditField.Enable = true;
                    app.TransparencyCheckBox.Enable = true;
                    app.ClimUpper.Enable = true; app.ClimLower.Enable = true;
                    app.ClimUpper.Visible = true; app.ClimLower.Visible = true;
                    app.ClimLabel.Visible = true;
                    app.BFdBLabel.Visible = false;
                    app.BFdBUpper.Visible = false;
                    app.BFdBLower.Visible = false;
                    if ~isempty(app.B_pow)
                        app.BeamformerPowerButton.Enable = true;
                    else
                        app.BeamformerPowerButton.Enable = false;
                    end
                    app.PlotTypeButtonGroup.SelectedObject = app.SpectrogramButton;
                    PlotTypeButtonGroupSelectionChanged(app, event)
                    updateSpecPlot(app)
                    % app.KeepLimitsCheckBox.Visible = false;
            end
        end

        % Callback function
        function BFLowerValueChanged(app, event)
            value = app.BFLower.Value;
            if ~isempty(app.S)
                updateBFData(app)
                CenterTabSelectionChanged(app, event)
            end
        end

        % Callback function
        function BFUpperValueChanged(app, event)
            value = app.BFUpper.Value;
            updateBFData(app)
            CenterTabSelectionChanged(app, event)
        end

        % Value changed function: XlimLower
        function XlimLowerValueChanged(app, event)
            value = app.XlimLower.Value;
            xa = xlim(app.UIAxes);
            xlim(app.UIAxes,[value,xa(2)])    
        end

        % Value changed function: XlimUpper
        function XlimUpperValueChanged(app, event)
            value = app.XlimUpper.Value;
            xa = xlim(app.UIAxes);
            xlim(app.UIAxes,[xa(1),value])    
        end

        % Value changed function: ResampleCheckBox
        function ResampleCheckBoxValueChanged(app, event)
            value = app.ResampleCheckBox.Value;
            if ~value
                app.SampleRate.Value = app.OriginalSampleRate;
                app.NFFT.Value = 2^nextpow2(app.SampleRate.Value/app.FreqRes.Value); 
                app.SampleRate.Enable = false;
            else
                app.SampleRate.Enable = true;
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound
        end

        % Value changed function: TransparencyCheckBox
        function TransparencyCheckBoxValueChanged(app, event)
            if ~isempty(app.S)
                updateSpecPlot(app)
            end
        end

        % Value changed function: BFdBLower
        function BFdBLowerValueChanged(app, event)
            value = app.BFdBLower.Value;
            ca = clim(app.UIAxesBF);
            clim(app.UIAxesBF,[value,ca(2)])
        end

        % Value changed function: BFdBUpper
        function BFdBUpperValueChanged(app, event)
            value = app.BFdBUpper.Value;
            ca = clim(app.UIAxesBF);
            clim(app.UIAxesBF,[ca(1), value])
        end

        % Value changed function: NFFT
        function NFFTValueChanged(app, event)
            app.FreqRes.Value = app.SampleRate.Value / app.NFFT.Value;
            
        end

        % Value changed function: NavigatebyfileCheckBox
        function NavigatebyfileCheckBoxValueChanged(app, event)
            value = app.NavigatebyfileCheckBox.Value;
            if value 
                app.SelectDuration.Value = 60;
                app.SelectDuration.Enable = false;
                app.SelectStep.Value = 60;
                app.SelectStep.Enable = false;
            else
                app.SelectDuration.Enable = true;
                app.SelectStep.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.FileNavigatorPanel = uipanel(app.LeftPanel);
            app.FileNavigatorPanel.Title = 'File Navigator';
            app.DirectoryButton = uibutton(app.FileNavigatorPanel, 'push');
            app.DirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @DirectoryButtonPushed, true);
            app.DirectoryButton.Text = 'Directory';
            app.SelectDirectory = uieditfield(app.FileNavigatorPanel, 'text');
            app.SelectDirectory.Editable = 'off';
            app.DateDatePickerLabel = uilabel(app.FileNavigatorPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.SelectDate = uidatepicker(app.FileNavigatorPanel);
            app.SelectDate.DisplayFormat = 'uuuu-MM-dd';
            app.SelectDate.ValueChangedFcn = createCallbackFcn(app, @SelectDateValueChanged, true);
            app.DurationsEditFieldLabel = uilabel(app.FileNavigatorPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.SelectDuration = uieditfield(app.FileNavigatorPanel, 'numeric');
            app.SelectDuration.Limits = [0 Inf];
            app.SelectDuration.Value = 10;
            app.StepsLabel = uilabel(app.FileNavigatorPanel);
            app.StepsLabel.Text = 'Step (s)';
            app.SelectStep = uieditfield(app.FileNavigatorPanel, 'numeric');
            app.SelectStep.Limits = [0 Inf];
            app.SelectStep.Value = 5;
            app.DrifterDropDownLabel = uilabel(app.FileNavigatorPanel);
            app.DrifterDropDownLabel.Text = 'Drifter';
            app.SelectDrifter = uidropdown(app.FileNavigatorPanel);
            app.SelectDrifter.Items = {'4', '5', '6'};
            app.SelectDrifter.ValueChangedFcn = createCallbackFcn(app, @SelectDrifterValueChanged, true);
            app.SelectDrifter.Value = '5';
            app.ExperimentDropDownLabel = uilabel(app.FileNavigatorPanel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.FileNavigatorPanel);
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentDropDownValueChanged, true);
            app.SelectHour = uispinner(app.FileNavigatorPanel);
            app.SelectHour.Limits = [0 24];
            app.SelectHour.RoundFractionalValues = 'on';
            app.SelectMinute = uispinner(app.FileNavigatorPanel);
            app.SelectMinute.Limits = [0 59];
            app.SelectSecond = uispinner(app.FileNavigatorPanel);
            app.SelectSecond.Limits = [0 59];
            app.TimeUTCLabel = uilabel(app.FileNavigatorPanel);
            app.TimeUTCLabel.Text = 'Time UTC';
            app.NavigatebyfileCheckBox = uicheckbox(app.FileNavigatorPanel);
            app.NavigatebyfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @NavigatebyfileCheckBoxValueChanged, true);
            app.NavigatebyfileCheckBox.Text = 'Navigate by file';
            app.AudioPlaybackPanel = uipanel(app.LeftPanel);
            app.AudioPlaybackPanel.Tooltip = {'Audio playback will play the entire sound clip, regardless of axis limits'};
            app.AudioPlaybackPanel.Title = 'Audio Playback';
            app.PlayButton = uibutton(app.AudioPlaybackPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.AudioPlaybackPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FilterCheckBox = uicheckbox(app.AudioPlaybackPanel);
            app.FilterCheckBox.Tooltip = {'Playback will filter to the frequency band specified by the plot axes'};
            app.FilterCheckBox.Text = 'Filter';
            app.FilterCheckBox.Value = true;
            app.SpectrogramOptionsPanel = uipanel(app.LeftPanel);
            app.SpectrogramOptionsPanel.Title = 'Spectrogram Options';
            app.NFFTEditFieldLabel = uilabel(app.SpectrogramOptionsPanel);
            app.NFFTEditFieldLabel.Text = 'NFFT';
            app.NFFT = uieditfield(app.SpectrogramOptionsPanel, 'numeric');
            app.NFFT.ValueChangedFcn = createCallbackFcn(app, @NFFTValueChanged, true);
            app.OverlapEditFieldLabel = uilabel(app.SpectrogramOptionsPanel);
            app.OverlapEditFieldLabel.Text = '% Overlap';
            app.PercentOverlap = uieditfield(app.SpectrogramOptionsPanel, 'numeric');
            app.PercentOverlap.Value = 0.5;
            app.FsEditFieldLabel = uilabel(app.SpectrogramOptionsPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.SampleRate = uieditfield(app.SpectrogramOptionsPanel, 'numeric');
            app.SampleRate.ValueDisplayFormat = '%.0f';
            app.SampleRate.Value = 51000;
            app.FreqResEditFieldLabel = uilabel(app.SpectrogramOptionsPanel);
            app.FreqResEditFieldLabel.Text = 'Freq Res';
            app.FreqRes = uieditfield(app.SpectrogramOptionsPanel, 'numeric');
            app.FreqRes.Limits = [0 Inf];
            app.FreqRes.ValueChangedFcn = createCallbackFcn(app, @FreqResValueChanged, true);
            app.FreqRes.Value = 100;
            app.ResampleCheckBox = uicheckbox(app.SpectrogramOptionsPanel);
            app.ResampleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResampleCheckBoxValueChanged, true);
            app.ResampleCheckBox.Text = 'Resample';
            app.ResampleCheckBox.Value = true;
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RewindTime = uibutton(app.LeftPanel, 'push');
            app.RewindTime.ButtonPushedFcn = createCallbackFcn(app, @RewindTimeButtonPushed, true);
            app.RewindTime.Text = '<--';
            app.AdvanceTime = uibutton(app.LeftPanel, 'push');
            app.AdvanceTime.ButtonPushedFcn = createCallbackFcn(app, @AdvanceTimeButtonPushed, true);
            app.AdvanceTime.Text = '-->';
            app.RightPanel = uipanel(app.UIFigure);
            app.PlotTypeButtonGroup = uibuttongroup(app.RightPanel);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.SpectrogramButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.AVSAzigramButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.AVSAzigramButton.Text = 'AVS Azigram';
            app.AVSAzigramButton.Value = true;
            app.AVSElegramButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.AVSElegramButton.Text = 'AVS Elegram';
            app.NTVButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.NTVButton.Text = 'NTV';
            app.KEtoPERatioButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.KEtoPERatioButton.Text = 'KE to PE Ratio';
            app.KeepLimitsCheckBox = uicheckbox(app.PlotTypeButtonGroup);
            app.KeepLimitsCheckBox.Text = 'Keep Limits';
            app.KeepLimitsCheckBox.Value = true;
            app.BeamformerPowerButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.BeamformerPowerButton.Enable = 'off';
            app.BeamformerPowerButton.Text = 'Beamformer Power';
            app.SpectrogramPlotOptionsPanel = uipanel(app.RightPanel);
            app.SpectrogramPlotOptionsPanel.Title = 'Spectrogram Plot Options';
            app.NTVThresholdEditFieldLabel = uilabel(app.SpectrogramPlotOptionsPanel);
            app.NTVThresholdEditFieldLabel.Text = 'NTV Threshold';
            app.NTVThresholdEditField = uieditfield(app.SpectrogramPlotOptionsPanel, 'numeric');
            app.NTVThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @NTVThresholdEditFieldValueChanged, true);
            app.SNRThresholdEditFieldLabel = uilabel(app.SpectrogramPlotOptionsPanel);
            app.SNRThresholdEditFieldLabel.Text = 'SNR Threshold';
            app.SNRThresholdEditField = uieditfield(app.SpectrogramPlotOptionsPanel, 'numeric');
            app.SNRThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SNRThresholdEditFieldValueChanged, true);
            app.SNRThresholdEditField.Value = -Inf;
            app.ClimLower = uispinner(app.SpectrogramPlotOptionsPanel);
            app.ClimLower.ValueChangedFcn = createCallbackFcn(app, @ClimLowerValueChanged, true);
            app.ClimUpper = uispinner(app.SpectrogramPlotOptionsPanel);
            app.ClimUpper.ValueChangedFcn = createCallbackFcn(app, @ClimUpperValueChanged, true);
            app.YlimLower = uispinner(app.SpectrogramPlotOptionsPanel);
            app.YlimLower.ValueChangedFcn = createCallbackFcn(app, @YlimLowerValueChanged, true);
            app.YlimUpper = uispinner(app.SpectrogramPlotOptionsPanel);
            app.YlimUpper.ValueChangedFcn = createCallbackFcn(app, @YlimUpperValueChanged, true);
            app.YlimUpper.Value = 25;
            app.ClimLabel = uilabel(app.SpectrogramPlotOptionsPanel);
            app.ClimLabel.Text = 'Clim';
            app.FkHzLabel = uilabel(app.SpectrogramPlotOptionsPanel);
            app.FkHzLabel.Text = 'F (kHz)';
            app.TransparencyCheckBox = uicheckbox(app.SpectrogramPlotOptionsPanel);
            app.TransparencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransparencyCheckBoxValueChanged, true);
            app.TransparencyCheckBox.Tooltip = {'Transparency is based on NTV'};
            app.TransparencyCheckBox.Text = 'Transparency';
            app.ChDropDownLabel = uilabel(app.SpectrogramPlotOptionsPanel);
            app.ChDropDownLabel.Text = 'Ch';
            app.Channel = uidropdown(app.SpectrogramPlotOptionsPanel);
            app.Channel.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.Channel.ValueChangedFcn = createCallbackFcn(app, @ChannelValueChanged, true);
            app.Channel.Value = '1';
            app.ElLabel = uilabel(app.SpectrogramPlotOptionsPanel);
            app.ElLabel.Text = 'El';
            app.PlotElev = uispinner(app.SpectrogramPlotOptionsPanel);
            app.PlotElev.ValueChangedFcn = createCallbackFcn(app, @PlotElevValueChanged2, true);
            app.XlimLower = uispinner(app.SpectrogramPlotOptionsPanel);
            app.XlimLower.ValueChangedFcn = createCallbackFcn(app, @XlimLowerValueChanged, true);
            app.XlimUpper = uispinner(app.SpectrogramPlotOptionsPanel);
            app.XlimUpper.ValueChangedFcn = createCallbackFcn(app, @XlimUpperValueChanged, true);
            app.TsLabel = uilabel(app.SpectrogramPlotOptionsPanel);
            app.TsLabel.Text = 'T (s)';
            app.BFdBLower = uispinner(app.SpectrogramPlotOptionsPanel);
            app.BFdBLower.ValueChangedFcn = createCallbackFcn(app, @BFdBLowerValueChanged, true);
            app.BFdBLower.Visible = 'off';
            app.BFdBLower.Value = 50;
            app.BFdBUpper = uispinner(app.SpectrogramPlotOptionsPanel);
            app.BFdBUpper.ValueChangedFcn = createCallbackFcn(app, @BFdBUpperValueChanged, true);
            app.BFdBUpper.Visible = 'off';
            app.BFdBUpper.Value = 160;
            app.BFdBLabel = uilabel(app.SpectrogramPlotOptionsPanel);
            app.BFdBLabel.Visible = 'off';
            app.BFdBLabel.Text = 'BF dB';
            app.RunBeamformerButton = uibutton(app.RightPanel, 'push');
            app.RunBeamformerButton.ButtonPushedFcn = createCallbackFcn(app, @RunBeamformerButtonPushed, true);
            app.RunBeamformerButton.Text = 'Run Beamformer';
            app.RecalculatefornewdataCheckBox = uicheckbox(app.RightPanel);
            app.RecalculatefornewdataCheckBox.Text = 'Recalculate for new data';
            app.CenterTab = uitabgroup(app.UIFigure);
            app.CenterTab.SelectionChangedFcn = createCallbackFcn(app, @CenterTabSelectionChanged, true);
            app.SpectrogramTab = uitab(app.CenterTab);
            app.SpectrogramTab.Title = 'Spectrogram';
            app.UIAxes = uiaxes(app.SpectrogramTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BeamformerPlotsTab = uitab(app.CenterTab);
            app.BeamformerPlotsTab.Tooltip = {'You can limit the times and frequency range of the beamformer by changing the Spectrogram Plot Option limits'};
            app.BeamformerPlotsTab.Title = 'Beamformer Plots';
            app.UIAxesBF = uiaxes(app.BeamformerPlotsTab);
            title(app.UIAxesBF, 'Title')
            xlabel(app.UIAxesBF, 'X')
            ylabel(app.UIAxesBF, 'Y')
            zlabel(app.UIAxesBF, 'Z')
            app.HistogramsTab = uitab(app.CenterTab);
            app.HistogramsTab.Tooltip = {'You can limit the times and frequency range of the histogram by changing the Spectrogram Plot Option limits'};
            app.HistogramsTab.Title = 'Histograms';
            app.UIAxesHist = uiaxes(app.HistogramsTab);
            title(app.UIAxesHist, 'Title')
            xlabel(app.UIAxesHist, 'X')
            ylabel(app.UIAxesHist, 'Y')
            zlabel(app.UIAxesHist, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drifter_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end