classdef drifter_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ExperimentNameLabel      matlab.ui.control.Label
        VectorSensorLabel        matlab.ui.control.Label
        AudioPlaybackPanel       matlab.ui.container.Panel
        FileNavigatorPanel       matlab.ui.container.Panel
        SelectStep               matlab.ui.control.NumericEditField
        StepsLabel               matlab.ui.control.Label
        SelectDuration           matlab.ui.control.NumericEditField
        DurationsEditFieldLabel  matlab.ui.control.Label
        SelectSecond             matlab.ui.control.NumericEditField
        SelectMinute             matlab.ui.control.NumericEditField
        SelectHour               matlab.ui.control.NumericEditField
        DirectoryButton          matlab.ui.control.Button
        FileDropDown             matlab.ui.control.DropDown
        FileDropDownLabel        matlab.ui.control.Label
        SelectDate               matlab.ui.control.DatePicker
        DateDatePickerLabel      matlab.ui.control.Label
        SelectDrifter            matlab.ui.control.DropDown
        DrifterDropDownLabel     matlab.ui.control.Label
        SelectDirectory          matlab.ui.control.EditField
        CenterPanel              matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        PlotTab                  matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        RewindTime               matlab.ui.control.Button
        AdvanceTime              matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        MapTab                   matlab.ui.container.Tab
        GridLayout5              matlab.ui.container.GridLayout
        RightPanel               matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        PlotTypeButtonGroup      matlab.ui.container.ButtonGroup
        KEtoPERatioButton        matlab.ui.control.RadioButton
        NTVButton                matlab.ui.control.RadioButton
        PlotButton               matlab.ui.control.Button
        ElegramButton            matlab.ui.control.RadioButton
        AzigramButton            matlab.ui.control.RadioButton
        SpectrogramButton        matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        time_start_utc
        time_stop_utc
        drifter_num
        metrics
        plot_freq_lim
        plot_time_lim
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            use_remote = false;
            [datadir,procdata_basedir,gitpath,envdir] = setUpDrifterPaths(use_remote,1);
            app.SelectDirectory.Value = datadir;
            app.SelectDate.Value = datetime(2023,2,27);
            app.SelectHour.Value = 7;
            app.SelectMinute.Value = 31;
            app.SelectSecond.Value = 28;
            app.SelectDuration.Value = 30;

            app.time_start_utc = datetime(year(app.SelectDate.Value),month(app.SelectDate.Value),day(app.SelectDate.Value),...
                app.SelectHour.Value,app.SelectMinute.Value,app.SelectSecond.Value);
            app.time_stop_utc = app.time_start_utc + seconds(app.SelectDuration.Value);
            app.drifter_num = str2double(app.SelectDrifter.Value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.time_start_utc = datetime(year(app.SelectDate.Value),month(app.SelectDate.Value),day(app.SelectDate.Value),...
                app.SelectHour.Value,app.SelectMinute.Value,app.SelectSecond.Value);
            app.time_stop_utc = app.time_start_utc + seconds(app.SelectDuration.Value);
            
            [y,t_utc,fs0,filetimes,~,cfg_file] = readDrifterDataV2(app.drifter_num,app.time_start_utc,app.time_stop_utc,app.SelectDirectory.Value);
            t_sec = convertTo(t_utc,'posixtime'); t_sec = t_sec - t_sec(10);

            do_filter = true;
            do_resample = true;

            fres = 100;
            fs_resample = 35e3;
            f1 = 2.5e3; f2 = 10e3;

            if do_resample
                fs = fs_resample;
                [y,t_sec] = resample(y,t_sec,fs());
            else
                fs = fs0;
            end
            
            if do_filter
                [bb,aa]= butter(4,2*[f1 f2]/fs);
                y = filter(bb,aa,y);
            end
            
            nfft = 2^nextpow2(fs/fres); 
            noverlp = floor(nfft*0.5);
            % [S,F,T,ch_select,sensor_type] = getDrifterSpectrogramV2(y,nfft,floor(nfft*0.5),fs,cfg_file{1},'stft');
            
            [S,F,T,ch_select,sensor_type] = getDrifterSTFTV2(y,nfft,noverlp,fs,cfg_file{1},'stft');

            P = S(:,:,9);
            V = S(:,:,10:12);
            
            app.metrics = computeDirectionalMetrics(P,V,1);
            app.metrics.F = F;
            app.metrics.T = T;

            PlotTypeButtonGroupSelectionChanged(app, event)

        end

        % Value changed function: SelectDrifter
        function SelectDrifterValueChanged(app, event)
            app.drifter_num = str2double(app.SelectDrifter.Value);
            
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotTypeButtonGroup.SelectedObject;
            
            fmax = 15e3;

            P_dB = 20*log10(abs(app.metrics.P));
            transp = app.metrics.normalized_transport_velocity;
            
            switch selectedButton.Text
                case 'Spectrogram'
                    p=pcolor(app.UIAxes,app.metrics.T,app.metrics.F/1e3,P_dB);
                    shading(app.UIAxes,'flat');% 
                    colorbar(app.UIAxes)
                    colormap(app.UIAxes,'jet')
                    clim(app.UIAxes,[40 180])
                    ylim(app.UIAxes,[0 fmax/1e3])
                    xlim(app.UIAxes,[app.metrics.T(1) app.metrics.T(end)])
                    set(app.UIAxes,'FontSize',14,'FontWeight','bold')
                    title(app.UIAxes,'PSD')
                    xlabel(app.UIAxes,'Time, s')
                    ylabel(app.UIAxes,'Freq, kHz')
                case 'Azigram'
                    p=pcolor(app.UIAxes,app.metrics.T,app.metrics.F/1e3,wrapTo180(app.metrics.azigram)); 
                    shading(app.UIAxes,'flat');% 
                    p.AlphaData = transp; 
                    p.FaceAlpha = 'flat';
                    p.AlphaDataMapping = 'none';
                    colorbar(app.UIAxes)
                    colormap(app.UIAxes,'hsv')
                    clim(app.UIAxes,[-180 180])
                    ylim(app.UIAxes,[0 fmax/1e3])
                    xlim(app.UIAxes,[app.metrics.T(1) app.metrics.T(end)])
                    set(app.UIAxes,'FontSize',14,'FontWeight','bold')
                    title(app.UIAxes,'AVS Azigram')
                    xlabel(app.UIAxes,'Time, s')
                    ylabel(app.UIAxes,'Freq, kHz')
                case 'Elegram'
                    p=pcolor(app.UIAxes,app.metrics.T,app.metrics.F/1e3,wrapTo180(app.metrics.elegram)); 
                    shading(app.UIAxes,'flat');% 
                    p.AlphaData = transp; 
                    p.FaceAlpha = 'flat';
                    p.AlphaDataMapping = 'none';
                    colorbar(app.UIAxes)
                    colormap(app.UIAxes,'jet')
                    clim(app.UIAxes,[-90 90])
                    ylim(app.UIAxes,[0 fmax/1e3])
                    xlim(app.UIAxes,[app.metrics.T(1) app.metrics.T(end)])
                    set(app.UIAxes,'FontSize',14,'FontWeight','bold')
                    title(app.UIAxes,'AVS Elegram')
                    xlabel(app.UIAxes,'Time, s')
                    ylabel(app.UIAxes,'Freq, kHz')
            end
        end

        % Value changed function: SelectDuration
        function SelectDurationValueChanged(app, event)
            value = app.SelectDuration.Value;
            
        end

        % Button pushed function: AdvanceTime
        function AdvanceTimePushed(app, event)
            app.time_start_utc = app.time_start_utc + seconds(app.SelectStep.Value);
            app.time_stop_utc = app.time_stop_utc + seconds(app.SelectStep.Value);

            app.SelectDate.Value = app.time_start_utc;
            app.SelectHour.Value = hour(app.time_start_utc);
            app.SelectMinute.Value = minute(app.time_start_utc);
            app.SelectSecond.Value = second(app.time_start_utc);

            PlotButtonPushed(app, event)
        end

        % Button pushed function: RewindTime
        function RewindTimeButtonPushed(app, event)
            app.time_start_utc = app.time_start_utc - seconds(app.SelectStep.Value);
            app.time_stop_utc = app.time_stop_utc - seconds(app.SelectStep.Value);

            app.SelectDate.Value = app.time_start_utc;
            app.SelectHour.Value = hour(app.time_start_utc);
            app.SelectMinute.Value = minute(app.time_start_utc);
            app.SelectSecond.Value = second(app.time_start_utc);

            PlotButtonPushed(app, event)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {483, 483, 483};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {483, 483};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {238, '1x', 235};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileNavigatorPanel = uipanel(app.LeftPanel);
            app.FileNavigatorPanel.Title = 'File Navigator';
            app.SelectDirectory = uieditfield(app.FileNavigatorPanel, 'text');
            app.DrifterDropDownLabel = uilabel(app.FileNavigatorPanel);
            app.DrifterDropDownLabel.Text = 'Drifter';
            app.SelectDrifter = uidropdown(app.FileNavigatorPanel);
            app.SelectDrifter.Items = {'4', '5', '6'};
            app.SelectDrifter.ValueChangedFcn = createCallbackFcn(app, @SelectDrifterValueChanged, true);
            app.SelectDrifter.Value = '5';
            app.DateDatePickerLabel = uilabel(app.FileNavigatorPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.SelectDate = uidatepicker(app.FileNavigatorPanel);
            app.FileDropDownLabel = uilabel(app.FileNavigatorPanel);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.FileNavigatorPanel);
            app.DirectoryButton = uibutton(app.FileNavigatorPanel, 'push');
            app.DirectoryButton.Text = 'Directory';
            app.SelectHour = uieditfield(app.FileNavigatorPanel, 'numeric');
            app.SelectMinute = uieditfield(app.FileNavigatorPanel, 'numeric');
            app.SelectSecond = uieditfield(app.FileNavigatorPanel, 'numeric');
            app.DurationsEditFieldLabel = uilabel(app.FileNavigatorPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.SelectDuration = uieditfield(app.FileNavigatorPanel, 'numeric');
            app.SelectDuration.ValueChangedFcn = createCallbackFcn(app, @SelectDurationValueChanged, true);
            app.StepsLabel = uilabel(app.FileNavigatorPanel);
            app.StepsLabel.Text = 'Step (s)';
            app.SelectStep = uieditfield(app.FileNavigatorPanel, 'numeric');
            app.AudioPlaybackPanel = uipanel(app.LeftPanel);
            app.AudioPlaybackPanel.Title = 'Audio Playback';
            app.VectorSensorLabel = uilabel(app.LeftPanel);
            app.VectorSensorLabel.Text = 'Vector Sensor';
            app.ExperimentNameLabel = uilabel(app.LeftPanel);
            app.ExperimentNameLabel.Text = 'Experiment Name';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout4 = uigridlayout(app.PlotTab);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AdvanceTime = uibutton(app.GridLayout4, 'push');
            app.AdvanceTime.ButtonPushedFcn = createCallbackFcn(app, @AdvanceTimePushed, true);
            app.AdvanceTime.Text = '->';
            app.RewindTime = uibutton(app.GridLayout4, 'push');
            app.RewindTime.ButtonPushedFcn = createCallbackFcn(app, @RewindTimeButtonPushed, true);
            app.RewindTime.Text = '<-';
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.GridLayout5 = uigridlayout(app.MapTab);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.PlotTypeButtonGroup = uibuttongroup(app.GridLayout3);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.SpectrogramButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.SpectrogramButton.Value = true;
            app.AzigramButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.AzigramButton.Text = 'Azigram';
            app.ElegramButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ElegramButton.Text = 'Elegram';
            app.PlotButton = uibutton(app.PlotTypeButtonGroup, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NTVButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.NTVButton.Text = 'NTV';
            app.KEtoPERatioButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.KEtoPERatioButton.Text = 'KE to PE Ratio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drifter_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end